classdef D3_Reconstruction_UI_Ver1c_Breast_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1           matlab.ui.Figure
        uipanel1          matlab.ui.container.Panel
        axes1             matlab.ui.control.UIAxes
        axes2             matlab.ui.control.UIAxes
        uipanel3          matlab.ui.container.Panel
        FileType          matlab.ui.control.EditField
        LoadImages        matlab.ui.control.Button
        SetRef            matlab.ui.control.Button
        listbox1          matlab.ui.control.ListBox
        text11            matlab.ui.control.Label
        text12            matlab.ui.control.Label
        text_ref          matlab.ui.control.Label
        text_dir          matlab.ui.control.Label
        textWarning       matlab.ui.control.Label
        SaveAxis          matlab.ui.control.Button
        Auto              matlab.ui.control.Button
        Reconstruction    matlab.ui.control.Button
        uipanel4          matlab.ui.container.Panel
        text6             matlab.ui.control.Label
        wavelength        matlab.ui.control.EditField
        NumIter           matlab.ui.control.EditField
        text7             matlab.ui.control.Label
        text8             matlab.ui.control.Label
        Dz                matlab.ui.control.EditField
        DzScan            matlab.ui.control.Button
        uipanel5          matlab.ui.container.Panel
        togglePhase       matlab.ui.control.StateButton
        toggleSupport     matlab.ui.control.StateButton
        toggleComposite   matlab.ui.control.StateButton
        toggleDetection   matlab.ui.control.Button
        uibuttongroup2    matlab.ui.container.ButtonGroup
        radiobuttonGray   matlab.ui.control.RadioButton
        radiobuttonColor  matlab.ui.control.RadioButton
        uipanel7          matlab.ui.container.Panel
        DataExport        matlab.ui.control.Button
        ExportFile        matlab.ui.control.EditField
        text9             matlab.ui.control.Label
        ExpDir            matlab.ui.control.CheckBox
        ExpFile           matlab.ui.control.CheckBox
        ExpHolo           matlab.ui.control.CheckBox
        ExpRecon          matlab.ui.control.CheckBox
        ExpRange          matlab.ui.control.CheckBox
        ExpObj            matlab.ui.control.CheckBox
        ExpComposite      matlab.ui.control.CheckBox
        ExpAmp            matlab.ui.control.CheckBox
        uipanel8          matlab.ui.container.Panel
        ThresholdObj      matlab.ui.control.EditField
        text10            matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function [Upsampled, dx2] = upsampling(Data, Pow2factor, dx1)
  
            Upsampled = interp2(Data,Pow2factor,'spline');
            dx2 = dx1/(2^Pow2factor);
      
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function D3_Reconstruction_UI_Ver1c_Breast_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to D3_Reconstruction_UI_Ver1c_Breast (see VARARGIN)
%             
%             % Choose default command line output for D3_Reconstruction_UI_Ver1c_Breast
%             
%             %Create tab group
%             app.tgroup = uitabgroup('Parent', app.figure1,'TabLocation', 'left');
%             app.tab1 = uitab('Parent', app.tgroup, 'Title', 'Breast Cancer');
%             app.tab2 = uitab('Parent', app.tgroup, 'Title', 'IL3');
%             %Place panels into each tab
%             set(app.uipanel1,'Parent',app.tab1)
%             set(app.uipanel2,'Parent',app.tab2)
            %Reposition each panel to same location as panel 1
%              set(app.uipanel2,'position',app.uipanel1,'position'));
    
%             app.output = hObject;
%             
%             % Update handles structure
%             guidata(hObject, handles);
        end

        % Button pushed function: Auto
        function Auto_Callback(app, event)
            % hObject    handle to Auto (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            ImageAxis = app.ImageAxis;
            numFiles = size(ImageAxis,1);
            ref = app.ref; 
           
            for i=1:numFiles
                if size(ImageAxis{i,1},1)
                    xlimit = ImageAxis{i,2};
                    ylimit = ImageAxis{i,3};
                    name = ImageAxis{i,1};
                    data = double(imread([app.text_dir,String),name]));
                    axes(app.axes1)
                    imagesc(data./ref);
                    colormap('gray')
                    caxis([0.65 1.25])
                    axis equal
                    xlim(xlimit)
                    ylim(ylimit)
                    title(['Raw hologram of ',ImageAxis{i,1}])
                    app.data = data;
                    app.name = name;
                    guidata(hObject, handles);
            
                    Reconstruction_Callback(app.Reconstruction, eventdata, handles)
                    toggleDetection_Callback(app.toggleDetection, eventdata, guidata(app.Reconstruction))
            
                end
            end
        end

        % Button pushed function: DzScan
        function DzScan_Callback(app, event)
            % hObject    handle to DzScan (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            axes(app.axes2)
            title('Calculating ...')
            pause(0.01)
            
            Data = app.data;
            Ref = app.ref;
            
            lambda = str2double(app.wavelength.Value))*1e-9;
            Dz_int = str2double(app.Dz.Value))*1e-4;
            
            
            delta2 = 2.2e-6;        % [m] pixel size of CCD
            
            k = 2*pi/lambda;    % wavevector
            
            % Get x and y-axis boundaries
            xlimit = app.axes1,'Xlim');
            ylimit = app.axes1,'Ylim');
            
            minx = ceil(xlimit(1));
            maxx = floor(xlimit(2));
            miny = ceil(ylimit(1));
            maxy = floor(ylimit(2));
            
            % Adjust min and max boundaries if the xlim and ylim is out of hologram image
            if minx < 1
                minx = 1;
            end
            if maxx > 2592
                maxx = 2592;
            end
            if miny < 1
                miny = 1;
            end
            if maxy > 1944
                maxy = 1944;
            end
            
            
            % Only reconstruct an image on the window to reduce computing time
            % global subNormAmp
            subNormAmp = sqrt(Data(miny:maxy,minx:maxx));
            subRef = sqrt(Ref(miny:maxy,minx:maxx));
            
            % if maxx-minx <= 256
            %     UpsampleFactor = 2;
            % else if maxx-minx <= 512
            %     UpsampleFactor = 1;
            % else
            %     UpsampleFactor = 0;
            %     end
            % end
            
            UpsampleFactor = 2;
            delta_prev = delta2;
            
            if UpsampleFactor > 0
                [subNormAmp,delta2] = upsampling(subNormAmp,UpsampleFactor,delta_prev);
                [subRef,delta2] = upsampling(subRef,UpsampleFactor,delta_prev);
            end
            
            Nx = size(subNormAmp,1);
            Ny = size(subNormAmp,2);
            delta1 = delta2;
            
            dfx = 1/(Nx*delta2);  % grid spacing in frequency domain
            dfy = 1/(Ny*delta2);
            
            [fx fy] = meshgrid((-Ny/2:Ny/2-1)*dfy,(-Nx/2:Nx/2-1)*dfx);
       
            DzTemp = 0;
            
            % global focus
            % global Dzmin
            Dzmin = Dz_int;
            for Dzscan = 1:30
                Dz = Dz_int + Dzscan*0.1e-4
            
            % Transformation function
            Gbp = zeros(Nx,Ny);
            Gfp = zeros(Nx,Ny);
            for n = 1:size(fx,1)
                for m=1:size(fx,2)
                    Gbp(n,m) = exp(1i*k*Dz*sqrt(1-lambda^2*fx(n,m)^2-lambda^2*fy(n,m)^2));
                    Gfp(n,m) = exp(-1i*k*Dz*sqrt(1-lambda^2*fx(n,m)^2-lambda^2*fy(n,m)^2));
                end
            end

            % Step1. Define object support from transmission constraint
            % Latychevskaia and Fink PRL 98, 233901 (2007)
            % Latychevskaia and Fink Opt Exp 17, 10697 (2009)
            % Zhao et al., Opt. Eng. 50, 091310 (2011)
            
            % The number of iteration
            % NumIteration = str2double(app.NumIter,'String'));
            % NumIteration = 1;
            
            % The measured hologram amplitude is used for an initial estimate
            Input = subNormAmp./subRef;
            % Input_Ref = subRef;
            
            % Reconstruction process
    
                % Fourier transform of function at the detector plane.
                F2 = ft2(Input,delta2);
            %     F2Ref = ft2(Input_Ref,delta2);
                % Reconstructed image at the object plane
                Recon1 = ift2(F2.*Gbp,dfx,dfy);
            %     ReconRef = ift2(F2Ref.*Gbp,dfx,dfy);

                Modulus = abs(Recon1);
                temp_focus = Modulus;%Modulus(100:200,100:200);
            %     temp_focus = Modulus(200:260,290:350);
 
                imagesc(Modulus);
            
                axis equal
                colormap(gray)
                title(['Dz = ',num2str(Dz)])
                caxis([0 1])
                pause(0.01)
            
                focus(Dzscan) = std(temp_focus(:));
                if focus(Dzscan) > DzTemp
                    DzTemp = focus(Dzscan);
                    Dzmin = Dz;
                    Final = Modulus;
            
                    imagesc(Modulus);
            %         pause(0.1)
                    axis equal
                    caxis([0 1])
                    colormap(gray)
                    title(['Dz = ',num2str(Dzmin)])
                    pause(0.01)
            
                end
            end
            
            imagesc(Final);
            pause(0.1)
            axis equal
            caxis([0 1])
            colormap(gray)
            title(['Dz = ',num2str(Dzmin)])
        end

        % Value changed function: ExportFile
        function ExportFile_Callback(app, event)
            % hObject    handle to ExportFile (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: hObject,'String') returns contents of ExportFile as text
            %        str2double(hObject,'String')) returns contents of ExportFile as a double
            
            
            % Export values selected in the list
            check = zeros(8,1);
            check(1) = app.ExpDir.Value;
            check(2) = app.ExpFile.Value;
            check(3) = app.ExpHolo.Value;
            check(4) = app.ExpRecon.Value;
            check(5) = app.ExpRange.Value;
            check(6) = app.ExpObj.Value;
            check(7) = app.ExpAmp.Value;
            check(8) = app.ExpComposite.Value;
            
            
            name=app.ExportFile.Value);
            
            ExportName = [name,'.mat'];


            SaveFiles = matfile(ExportName,'Writable',true);
            
            if check(1) == 1
                Directory = app.text_dir.Value);
                SaveFiles.Directory = Directory;
            end
            if check(2) == 1
                contents = app.listbox1.Value);
                FileName = contents{app.listbox1.Value};
                SaveFiles.FileName = FileName;
            end
            if check(3) == 1
                subNormAmp = app.Submap;
                SaveFiles.subNormAmp = subNormAmp;
            end
            if check(4) == 1
                ReconImage = app.Recon;
                SaveFiles.ReconImage = ReconImage;
            end
            if check(5) == 1
                Range = app.Range;
                SaveFiles.Range = Range;
            end
            if check(6) == 1
                ObjSupp = app.ObjSupp;
                SaveFiles.ObjSupp = ObjSupp;
            end
            if check(7) == 1
                Directory = app.text_dir.Value);
                Phase = app.Phase;
                ReconImage = app.Recon;
            
                Norm_Factor = 1.5;
            
            
                [m,n,r] = size(ReconImage);
                AmpGreen = zeros(m,n,3);
                PhaseRed = zeros(m,n,3);
                PhaseRed(:,:,1) = Phase;
                AmpGreen(:,:,2) = Norm_Factor*(1-abs(ReconImage));
            %     PhaseRed(1,1,1) = pi();
            %     AmpGreen(1,1,2) = pi();
                imwrite(PhaseRed,[Directory name '_phase.tif']);
                imwrite(AmpGreen,[Directory name '_amp.tif']);
            end
            
            if check(8) == 1
                Directory = app.text_dir.Value);
                Phase = app.Phase;
                ReconImage = app.Recon;
            
                Norm_Factor = 1.5;
                composite = imfuse(Norm_Factor*(1-abs(ReconImage)),Phase,'falsecolor','Scaling','joint','ColorChannels',[2 1 0]);
                imwrite(composite,[Directory name '_composite.tif']);
            end
        end

        % Callback function
        function LoadData_Callback(app, event)
            % hObject    handle to LoadData (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [filename, pathname] = uigetfile('*.mat','Pick a mat file');
            load([pathname, filename])
            
            app.Result = Result_Final;
            app.PairIndex = PairIndex;
            app.BeadCount = BeadCount;
            app.ReconFolder = ReconFolder;
            app.theNames = theNames;
            app.ReconNames = ReconNames;
   
            set(app.listbox1.Value,theNames)
            set(app.uitable2,'Data',Result_Final(2:size(Result_Final,1),2:size(Result_Final,2)))
            set(app.uitable2,'columnname',Result_Final(1,2:size(Result_Final,2)))
            set(app.uitable2,'rowname',Result_Final(2:size(Result_Final,1),1))
 
            guidata(hObject,handles)
        end

        % Button pushed function: LoadImages
        function LoadImages_Callback(app, event)
            % hObject    handle to LoadImages (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
%             set(app.listbox1);
            app.listbox1 = uilistbox('Items', []);
            
            % First get the folder name
            directory_name = uigetdir('','Choose a folder to import...');
            
            FileExtension = app.FileType.Value;
            
            theFiles = dir([directory_name,'/*.',FileExtension]);
            
            numFiles = size(theFiles,1);
            names = struct2cell(theFiles);
            app.listbox1.Items = names(1,:);
            app.text_dir.Value = [directory_name,'/'];
            
            % Show the first image in the list
            axes(app.axes1)
            imagesc(imread([directory_name,'/',theFiles(1).name]),[0,2^16]);
            colormap('gray')
            axis equal
            
            ImageAxis = cell(numFiles-1,3);
            app.ImageAxis = ImageAxis;
            
            guidata(hObject, handles);
        end

        % Button pushed function: Reconstruction
        function Reconstruction_Callback(app, event)
            % hObject    handle to Reconstruction (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % stop watch start
            tstart = tic;
            axes(app.axes2)
            title('Calculating ...')
            
            pause(0.01)
            
            Data = app.data;
            Ref = app.ref;
            
            % Setup info: Wavelength and sample-to-imager distance
            lambda = str2double(app.wavelength.Value)*1e-9;
            Dz = str2double(app.Dz.Value)*1e-4;
            
            
            delta2 = 2.2e-6;        % [m] pixel size of CCD
            
            k = 2*pi/lambda;    % wavevector
            
            % Get x and y-axis boundaries
            xlimit = app.axes1.XLim;
            ylimit = app.axes1.YLim;
            
            minx = ceil(xlimit(1));
            maxx = floor(xlimit(2));
            miny = ceil(ylimit(1));
            maxy = floor(ylimit(2));
            
            % Adjust min and max boundaries if the xlim and ylim is out of hologram image
            if minx < 1
                minx = 1;
            end
            if maxx > size(Data,2)
                maxx = size(Data,2);
            end
            if miny < 1
                miny = 1;
            end
            if maxy > size(Data,1)
                maxy = size(Data,1);
            end
            
            
            % Determine upsampling factor based on the image size
            if maxy-miny <= 256
                UpsampleFactor = 2;
            else if maxy-miny <= 512
                UpsampleFactor = 1;
            else
                UpsampleFactor = 0;
                end
            end
            
            % Force the upsampling factor to 2
            UpsampleFactor = 2;
            
            % When backround intensities are different between sample and ref images
             bgData = mean2(Data(miny:maxy,minx:maxx));
             bgRef = mean2(Ref(miny:maxy,minx:maxx));
             NormFactor = bgRef/bgData
            % NormFactor = 1;
            
            % Only reconstruct an image on the window to reduce computing time
            % if the transmission method is selected, the input value (subNormAmp) for
            % the iteration process should be normalized by the reference image.
            
            % subNormAmp = sqrt(Data(miny:maxy,minx:maxx)./(Ref(miny:maxy,minx:maxx)));
            subNormAmp = sqrt(Data(miny:maxy,minx:maxx)./(Ref(miny:maxy,minx:maxx))*NormFactor);
            
            
            delta_prev = delta2;
            
            if UpsampleFactor > 0
                [subNormAmp,delta2] = upsampling(subNormAmp,UpsampleFactor,delta_prev);
            end
            
            Nx = size(subNormAmp,1);
            Ny = size(subNormAmp,2);
            delta1 = delta2;
            
            dfx = 1/(Nx*delta2);  % grid spacing in frequency domain
            dfy = 1/(Ny*delta2);
            
            [fx fy] = meshgrid((-Ny/2:Ny/2-1)*dfy,(-Nx/2:Nx/2-1)*dfx);
            
            
            % Transformation function
            Gbp = zeros(Nx,Ny);
            Gfp = zeros(Nx,Ny);
            for n = 1:size(fx,1)
                for m=1:size(fx,2)
                    Gbp(n,m) = exp(1i*k*Dz*sqrt(1-lambda^2*fx(n,m)^2-lambda^2*fy(n,m)^2));
                    Gfp(n,m) = exp(-1i*k*Dz*sqrt(1-lambda^2*fx(n,m)^2-lambda^2*fy(n,m)^2));
                end
            end
            
            
            % The number of iteration
            NumIteration = str2double(app.NumIter.Value));
            
            % The measured hologram amplitude is used for an initial estimate
            Input = subNormAmp;
            
            
            %% Reconstruction process
            
            for k=1:NumIteration
            
                % Fourier transform of function at the detector plane.
                F2 = ft2(Input,delta2);
            
                % Reconstructed image at the object plane
                Recon1 = ift2(F2.*Gbp,dfx,dfy);
            
            
            %% Object support
                if k==1
            
                    temp = zeros(size(Recon1));
                    support = zeros(size(Recon1));
            %         Threshold_objsupp = 0.06;
            %         Threshold_objsupp = 0.040;
            
                    Threshold_objsupp = str2double(app.ThresholdObj.Value));
            
                    support = stdfilt(abs(Recon1).*cos(angle(Recon1)),ones(9));
                    support = im2bw(support,Threshold_objsupp);
                    se = strel('disk',6,0);
                    support = imdilate(support,se);
                    support = imfill(support,'holes');
                    support = bwareaopen(support,300);
            
                end
            
            %% Constraint
            
                % Preserve images inside the object support and set "1" to the
                % pixels outside of the object support
            
                Constraint = ones(size(Recon1));
            
                for p=1:size(Recon1,1)
                    for q=1:size(Recon1,2)
            
                        if support(p,q) == 1
                            Constraint(p,q) = abs(Recon1(p,q));
                        end
            
                % Transmission constraint
                % if the transmission is greater than 1, the value is set to unity.
                % basic assumption is the normalized transmission (absorption) value
                % cannot be greater (lower) than 1 (0). If it happens, it is due to
                % inteference with its twin image.
            
                        if abs(Recon1(p,q))>1
                            Constraint(p,q) = 1;
                        end
                    end
                end
            
            
            % togglePhase flipping
            % Zhao et al., Opt. Eng. 50, 091310 (2011)
            % flip the togglephase of object by changing the sign of the exponential term
            %     if k==1
            %         Recon1_update = Constraint.*exp(-1i*angle(Recon1));
            %     else
            %         Recon1_update = Constraint.*exp(1i*angle(Recon1));
            %     end
            
                Recon1_update = Constraint.*exp(1i*angle(Recon1));
            
            
                % Fourier transform of function at the object plane for transformation
                F1 = ft2(Recon1_update,delta1);
            
                % Reconstructed image at the detector plane
                Output = ift2(F1.*Gfp,dfx,dfy);
            
                % New input for the next iteration
                Input = subNormAmp.*exp(1i*angle(Output));
            
            
                % For the last iteration
                if k==NumIteration
                    Output_Final = Input;
                end
            end
            
            F2 = ft2(Output_Final,delta2);
            ReconImage = ift2(F2.*Gbp,dfx,dfy);
        
            
            Modulus = abs(ReconImage);
            Phase = angle(ReconImage);
            
            Phase_Revise = abs(Phase - mean2(Phase));
            for p=1:size(ReconImage,1)
                for q=1:size(ReconImage,2)
                    if Phase_Revise(p,q) > pi()
                        Phase_Revise(p,q) = 2*pi()-Phase_Revise(p,q);
                    end
                end
            end
            
            ExecTime = toc(tstart);
            axes(app.axes2)
            
            if app.radiobuttonColor.Value
                [m,n,r] = size(Modulus);
                ReconColor = zeros(m,n,3);
                ReconColor(:,:,2) = 1-abs(ReconImage);
                imagesc(ReconColor)
            end
            
            
            if app.radiobuttonGray.Value
                imagesc(Modulus)
                colormap(gray)
            end
            
            axis equal
            caxis([0 1])
            title(['Reconstructed amplitude after ',num2str(NumIteration),' iteration. Processing time = ', num2str(ExecTime),' sec'])


            % update normalized hologram and reconstructed image shown on the screen
            app.Recon = ReconImage;
            app.Submap = subNormAmp;
            app.Factor = UpsampleFactor;
            app.Range = [minx maxx miny maxy];
            app.NormAmp = Data;
            app.stdmap = temp;
            app.ObjSupp = support;
            app.Phase = Phase_Revise;
            % app.Phase = Phase;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: SaveAxis
        function SaveAxis_Callback(app, event)
            % hObject    handle to SaveAxis (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            name = app.name;
            index = app.listbox1.Value;
            ImageAxis = app.ImageAxis;
            xlimit = app.axes1.XLim;
            ylimit = app.axes1.YLim;
            
            ImageAxis{index,1} = name;
            ImageAxis{index,2} = xlimit;
            ImageAxis{index,3} = ylimit;
            ImageAxis
            
            app.ImageAxis = ImageAxis;
            guidata(hObject, handles);
        end

        % Button pushed function: SetRef
        function SetRef_Callback(app, event)
            % hObject    handle to SetRef (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            contents = app.listbox1.Value);
            ref = double(imread([app.text_dir,'String'),contents{app.listbox1.Value}]));
            
            set(app.text_ref,'String',contents{app.listbox1.Value});
            app.ref = ref;
            guidata(hObject, handles);
        end

        % Callback function
        function Start_Callback(app, event)
            % hObject    handle to Start (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            global circen cirrad mg_cnt ps_cnt pair_cnt Dist rows
            
            % Choose a folder of D3 image
            HPV_dir = uigetdir('//Home','Choose a folder to import...');
            set(app.text_Directory,'String',HPV_dir);
            pause(0.01)
            
            Set = str2double(app.Set,'String'));
            Repeat = str2double(app.Repeat,'String'));
            
            theFiles = dir([HPV_dir,'/*.png']);
            numFiles = size(theFiles,1);
            theData = cell(numFiles,1);
            theNames = cell(numFiles,1);
            
            % Check if the loaded image number is matched to set and repeat values
            % if numFiles-1 == Set*Repeat
            %     set(app.text_NumFiles,'String',['Number of images = ',num2str(Set*Repeat)])
            %     pause(0.01)
            % else
            %     set(app.text_NumFiles,'String','ERROR!!! Mismatch in the file number')
            %     return
            % end
            
            
            % read image data
            for i = 1:numFiles
                indexfile = strfind(theFiles(i).name,'.png');
            %     theData{i} = double(imread(fullfile(HPV_dir,theFiles(i).name)));
                theNames{i} = theFiles(i).name(1:indexfile-1);
                theData{i} = double(imread(fullfile(HPV_dir,theFiles(i).name)));
            end
            
            set(app.listbox2,'String',theNames);
            
            index = strfind(HPV_dir,'/D3');
            %
            % ReconFolder = [HPV_dir(1:index(2)),'input/',HPV_dir(index(2)+4:size(HPV_dir,2))]
            % ParameterFolder = [HPV_dir(1:index(2)),'input/HPV_Default']
            
            % % % % copy image files to the GPU folder
            % % % copyfile([HPV_dir,'/*.png'],ReconFolder,'f');
            % % %
            % % % % copy reconstruction parameter files
            % % % copyfile([ParameterFolder,'/*.*'],ReconFolder,'f');
            % % %
            % % % set(app.text_CopyFiles,'String','Copied files into the GPU folder')
            % % % pause(0.01)
            % % %
            % % % % wait for 10 min for image upload and reconstruction
            % % % for i=1:10
            % % %     set(app.text_Hold,'String',['Wait for downloading - ',num2str(i-1),' of 10'])
            % % %     pause(60)
            % % % end
            % % % set(app.text_Hold,'String','Wait for 10 min for downloading - Done')
            
            % check if all reconstructed images are downloaded
            
            Result = zeros(Set*Repeat,5);
            PairIndex = cell(Set*Repeat,1);
            BeadCount = cell(Set*Repeat,1);
            ReconNames = cell(Set*Repeat,1);
            
            
            interval = 128;
            kx = floor(size(theData{1},1)/interval)
            ky = floor(size(theData{1},2)/interval)
            ref = theData{numFiles};
            
            pair_cnt = zeros(kx,ky);
            ps_cnt = zeros(kx,ky);
            mg_cnt = zeros(kx,ky);


            for i=1:Set*Repeat


                for m = 1:kx
                    for n = 1:ky


                        rangex = (m-1)*interval+1:m*interval;
                        rangey = (n-1)*interval+1:n*interval;


                        ReconImage = Reconstruction(theData{i}(rangex,rangey),ref(rangex,rangey),405e-9,5e-4);
                        axes(app.axes3)
            
                        [o,p,q] = size(ReconImage);
                        ReconColor = zeros(o,p,3);
                        ReconColor(:,:,2) = 1-abs(ReconImage);
                        imagesc(ReconColor)
            
            
                        axis equal
                        caxis([0 1])
                        title([theNames{i}, '  ', num2str(rangex(1)), '-', num2str(rangey(1))])
                        pause(0.01)
                        [accum, circen, cirrad] = CircularHough_Grd(1-abs(ReconImage),[3 8],0.01,3);
            %             viscircles(circen,cirrad,'EdgeColor','b');
            
            %             pause()
            
                        circen = round(circen);
                        Amp = abs(ReconImage);
                        k_ps = 1;
                        k_mg = 1;
                        pos_ps=[];
                        pos_mg=[];
                        for k=1:size(circen,1)
            %                 Amp(circen(k,2),circen(k,1))
                            if Amp(circen(k,2),circen(k,1)) < 0.95
                                if cirrad(k) < 5.5
            
                                    pos_mg(k_mg,:) = circen(k,:);
                                    k_mg = k_mg+1;
            %                         viscircles(circen(k,:),cirrad(k),'EdgeColor','b');
                                else
                                    pos_ps(k_ps,:) = circen(k,:);
                                    k_ps = k_ps+1;
            %                         viscircles(circen(k,:),cirrad(k),'EdgeColor','g');
                                end
                            end
                        end
            
                        dist_threshold = 12;
            
                        Dist=[];
            
            
                        if k_ps>1 && k_mg>1
                            Dist = pdist2(pos_mg,pos_ps,'euclidean');
                        end


                        if ~isempty(Dist)
                            [rows,cols] = find(Dist<dist_threshold);
                            if size(rows,2)
                                pair_cnt(m,n) = size(rows,1);
                            else
                                pair_cnt(m,n) = 0;
                            end
                        else
                            pair_cnt(m,n) = 0;
                        end
                        ps_cnt(m,n) = k_ps-1;
                        mg_cnt(m,n) = k_mg-1;
            
                        title([theNames{i}, '  ', num2str(rangex(1)), '-', num2str(rangey(1)),' ps cnt: ', num2str(k_ps-1),'  mg cnt: ', num2str(k_mg-1),'  pair cnt: ', num2str(pair_cnt(m,n))])
                        pause(0.01)
            
                    end
                end
                BeadCount{i} = [ps_cnt, mg_cnt, pair_cnt];
                PS = sum(ps_cnt(:));
                MG = sum(mg_cnt(:));
                Pair = sum(pair_cnt(:));
            
                Result(i,1:4) = [PS MG Pair Pair/PS/MG*1e5];
                set(app.uitable2,'Data',Result)
                pause(0.01)
            end
            
            t = app.uitable2;
            t.RowName = theNames(1:Set*Repeat);
            
            row = app.uitable2,'rowname');
            col = app.uitable2,'columnname')';
            col = ['filename' col];
            
            Result_Final = [row num2cell(Result)];
            Result_Final = [col;Result_Final];
            
            % export data
            filename = [HPV_dir,'/Data_',HPV_dir(index(2)+4:size(HPV_dir,2)),'.mat']
            save(filename,'HPV_dir','Result_Final','PairIndex','theNames')
            
            set(app.text_Export,'String','Exported data')
        end

        % Value changed function: listbox1
        function listbox1_Callback(app, event)
            % hObject    handle to listbox1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(hObject,'String')) returns listbox1 contents as cell array
            %        contents{hObject.Value} returns selected item from listbox1
            
            contents = hObject,'String');
            
            xlimit = app.axes1,'Xlim');
            ylimit = app.axes1,'Ylim');
            
            minx = ceil(xlimit(1));
            maxx = floor(xlimit(2));
            miny = ceil(ylimit(1));
            maxy = floor(ylimit(2));
            
            if minx<1
                minx = 1;
            end
            
            if miny<1
                miny = 1;
            end
            
            if maxx>2592
                maxx = 2592;
            end
            
            if maxy>1944
                maxy = 1944;
            end
            
            data = double(imread([app.text_dir,'String'),contents{hObject.Value}]));
            
            axes(app.axes1)
            
            % Show the normalized data if a reference image is selected.
            RefLoaded = app.text_ref,'String');
            if strcmp(RefLoaded,'Not loaded')
                imagesc(data)
            else
                % When backround intensities are different between sample and ref images
                ref = app.ref;
            
                bgData = mean2(data(miny:maxy,minx:maxx));
                bgRef = mean2(ref(miny:maxy,minx:maxx));
                NormFactor = bgRef/bgData
                imagesc(data./ref*NormFactor);


                if abs(NormFactor-1)>0.1
                    set(app.textWarning,'String','The background intensity is off!')
                end
            
            %     NormFactor = 1;
            %     imagesc(data./ref);
            
                caxis([0.65 1.25])
            end
            colormap('gray')
            axis equal
            xlim(xlimit)
            ylim(ylimit)
            title(['Raw hologram of ',contents{hObject.Value}])
            
            % Check if the measured hologram is saturated.
            if max(data(:)) >= 2^16
                set(app.textWarning,'String','The measured hologram is saturated!')
            else
                set(app.textWarning,'String','The measured hologram is ok!')
            end
            
            app.data = data;
            app.name = contents{hObject.Value};
            
            guidata(hObject, handles);
        end

        % Value changed function: toggleComposite
        function toggleComposite_Callback(app, event)
            % hObject    handle to toggleComposite (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: hObject.Value returns toggle state of toggleComposite
            
            Phase = app.Phase;
            
            Phasebackground = mean2(Phase)*3;
            Phase = Phase-Phasebackground;
            
            ReconImage = app.Recon;
            Amp = 1-abs(ReconImage);
            
            for i=1:size(Phase,1)
                for j=1:size(Phase,2)
                    if Phase(i,j)<0
                        Phase(i,j)=0;
                    end
            
                    if Amp(i,j)<0
                        Amp(i,j)=0;
                    end
                end
            end
            
            Norm_Factor = 1;
                composite_image = imfuse(Norm_Factor*Amp,Phase,'falsecolor','Scaling','joint','ColorChannels',[2 1 0]);
            
            if hObject.Value
            
                imagesc(composite_image)
            
            else
            
            
                    if app.radiobuttonColor.Value
                        [m,n,r] = size(ReconImage);
                        ReconColor = zeros(m,n,3);
                        ReconColor(:,:,2) = Amp;
                        imagesc(ReconColor)
                    end
            
            
                    if app.radiobuttonGray.Value
                        imagesc(rgb2gray(composite_image))
                        colormap(gray)
                    end
                end
                axis equal
            %     caxis([0 1])
                title('Reconstructed amplitude')
        end

        % Button pushed function: toggleDetection
        function toggleDetection_Callback(app, event)
            % hObject    handle to toggleDetection (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            tstart = tic;
            
            axes(app.axes2)
            title('Detecting beads ...')
            pause(0.01)


            support = app.ObjSupp;
            Phase = app.Phase;
            Phasebackground = mean2(Phase)*3;
            Phase = Phase-Phasebackground;


            ReconImage = app.Recon;
            Amp = 1-abs(ReconImage);
            
            for i=1:size(Phase,1)
                for j=1:size(Phase,2)
                    if Phase(i,j)<0
                        Phase(i,j)=0;
                    end
            
                    if Amp(i,j)<0
                        Amp(i,j)=0;
                    end
                end
            end
            
            Norm_Factor = 2;
            composite = imfuse(Norm_Factor*Amp,Phase,'falsecolor','Scaling','joint','ColorChannels',[2 1 0]);
            I = rgb2gray(composite);


            [pos_amp,radii_amp] = imfindcircles(Amp,[4,9],'ObjectPolarity','bright','Sensitivity',0.9);
            [pos_phase,radii_phase] = imfindcircles(I,[10,30],'ObjectPolarity','bright','Sensitivity',0.85);
            % [pos_amp,radii_amp] = imfindcircles(I,[10,30],'ObjectPolarity','bright','Sensitivity',0.85);
            
            % [pos_phase,radii_phase] = imfindcircles(Phase,[7,24],'ObjectPolarity','bright','Sensitivity',0.9);
            
            
            radii_phase
            
            
            % [accum, posamp, radii_amp] = CircularHough_Grd(1-Amp,[3 9],0.01,3);
            % [accum, pos_phase, radii_phase] = CircularHough_Grd(Phase,[3 9],0.005,10);
            % [accum, pos_phase2, radii_phase2] = CircularHough_Grd(Phase,[10 20],0.005,10);


            viscircles(pos_phase,radii_phase,'EdgeColor','w');
            
            % round up the position coordinate
            pos_amp = round(pos_amp);
            pos_phase = round(pos_phase);
            
            global IntCell PhaseCell
            % global IntCell IntCell1 IntCell2 IntCell3 IntCell4 IntCell5 IntCell6
            % global PhaseCell1 PhaseCell2 PhaseCell3 PhaseCell4 PhaseCell5 PhaseCell6
            
            IntCell = [];
            PhaseCell = [];
            % IntCell1 =[];
            % IntCell2 = [];
            % IntCell3 = [];
            % IntCell4 = [];
            % IntCell5 = [];
            % IntCell6 = [];
            % PhaseCell1 = [];
            % PhaseCell2 = [];
            % PhaseCell3 = [];
            % PhaseCell4 = [];
            % PhaseCell5 = [];
            % PhaseCell6 = [];
            
            mask = zeros(21,21);
            for i=1:size(mask,1)
                for j=1:size(mask,2)
                    if (i-11)^2+(j-11)^2 <= 49
                        mask(i,j) = 1;
                    end
                end
            end
            
            mask2 = zeros(31,31);
            for i=1:size(mask,1)
                for j=1:size(mask2,2)
                    if (i-15)^2+(j-15)^2 <= 15^2
                        mask2(i,j) = 1;
                    end
                end
            end
            
            
            %% Find beads
            
            k_bead = 1;
            margin = 30;
            for i=1:size(pos_amp,1)
                if support(pos_amp(i,2),pos_amp(i,1))
                    if radii_amp(i) < 10
                        if pos_amp(i,1)>margin && pos_amp(i,2)>margin && pos_amp(i,1)+margin<size(Phase,2) && pos_amp(i,2)+margin<size(Phase,1)
                            bead_phase = sum(sum(Phase(pos_amp(i,2)-5:pos_amp(i,2)+5,pos_amp(i,1)-5:pos_amp(i,1)+5)))/121;
            
                            if bead_phase <= 0.5;
            
                                pos_bead(k_bead,:) = pos_amp(i,:);
            %                     viscircles(pos_amp(i,:),radii_amp(i),'EdgeColor','b');
                                k_bead = k_bead+1;
                            end
                        end
                    end
                end
            end


            %% Find Cells
            k_cell = 1;
            margin = 30;
            for i=1:size(pos_phase,1)
            
                if support(pos_phase(i,2),pos_phase(i,1))
                    if radii_phase(i)>=8
            
                    if pos_phase(i,1)>margin && pos_phase(i,2)>margin && pos_phase(i,1)+margin<size(Phase,2) && pos_phase(i,2)+margin<size(Phase,1)
            
                        IntCell(k_cell,1) = sum(sum(Amp(pos_phase(i,2)-10:pos_phase(i,2)+10,pos_phase(i,1)-10:pos_phase(i,1)+10).*mask))/sum(sum(mask));
                        IntCell(k_cell,2) = sum(sum(Amp(pos_phase(i,2)-10:pos_phase(i,2)+10,pos_phase(i,1)-10:pos_phase(i,1)+10)));
                        IntCell(k_cell,3) = sum(sum(Amp(pos_phase(i,2)-5:pos_phase(i,2)+5,pos_phase(i,1)-5:pos_phase(i,1)+5)));
                        IntCell(k_cell,4) = sum(sum(Amp(pos_phase(i,2)-3:pos_phase(i,2)+3,pos_phase(i,1)-3:pos_phase(i,1)+3)));
                        IntCell(k_cell,5) = Amp(pos_phase(i,2),pos_phase(i,1));
                        IntCell(k_cell,6) = sum(sum(Amp(pos_phase(i,2)-15:pos_phase(i,2)+15,pos_phase(i,1)-15:pos_phase(i,1)+15)));
                        IntCell(k_cell,7) = sum(sum(Amp(pos_phase(i,2)-15:pos_phase(i,2)+15,pos_phase(i,1)-15:pos_phase(i,1)+15).*mask2))/sum(sum(mask2));
            
                        radii_phase(i) = round(radii_phase(i));
                        mask3 = zeros(radii_phase(i)*2+1,radii_phase(i)*2+1);
                        for a=1:size(mask3,1)
                            for b=1:size(mask3,2)
                                if (a-radii_phase(i))^2+(b-radii_phase(i))^2 <= radii_phase(i)^2
                                    mask3(a,b) = 1;
                                end
                            end
                        end
                        mask3;
                        IntCell(k_cell,8) = sum(sum(Amp(pos_phase(i,2)-radii_phase(i):pos_phase(i,2)+radii_phase(i),pos_phase(i,1)-radii_phase(i):pos_phase(i,1)+radii_phase(i)).*mask3))/sum(sum(mask3));
            
                        mask4 = zeros(radii_phase(i)*2+1,radii_phase(i)*2+1);
                        for a=1:size(mask4,1)
                            for b=1:size(mask4,2)
                                if (a-radii_phase(i))^2+(b-radii_phase(i))^2 <= (radii_phase(i)-6)^2
                                    mask4(a,b) = 1;
                                end
                            end
                        end
                        IntCell(k_cell,9) = sum(sum(Amp(pos_phase(i,2)-radii_phase(i):pos_phase(i,2)+radii_phase(i),pos_phase(i,1)-radii_phase(i):pos_phase(i,1)+radii_phase(i)).*mask4))/sum(sum(mask4));
            
                        PhaseCell(k_cell,1) = sum(sum(Phase(pos_phase(i,2)-10:pos_phase(i,2)+10,pos_phase(i,1)-10:pos_phase(i,1)+10).*mask))/sum(sum(mask));
                        PhaseCell(k_cell,2) = sum(sum(Phase(pos_phase(i,2)-10:pos_phase(i,2)+10,pos_phase(i,1)-10:pos_phase(i,1)+10)));
                        PhaseCell(k_cell,3) = sum(sum(Phase(pos_phase(i,2)-5:pos_phase(i,2)+5,pos_phase(i,1)-5:pos_phase(i,1)+5)));
                        PhaseCell(k_cell,4) = sum(sum(Phase(pos_phase(i,2)-3:pos_phase(i,2)+3,pos_phase(i,1)-3:pos_phase(i,1)+3)));
                        PhaseCell(k_cell,5) = Phase(pos_phase(i,2),pos_phase(i,1));
                        PhaseCell(k_cell,6) = sum(sum(Phase(pos_phase(i,2)-15:pos_phase(i,2)+15,pos_phase(i,1)-15:pos_phase(i,1)+15)));
            
            
                        pos_cell(k_cell,:) = pos_phase(i,:);
                        rad_cell(k_cell) = radii_phase(i);
            
                        viscircles(pos_phase(i,:),radii_phase(i),'EdgeColor','r');
            
                        k_cell = k_cell+1;
                    end
                    end
                end
            end
            
            IntCell
            sum(IntCell)/size(IntCell,1)
            
            ExecTime = toc(tstart)
            axes(app.axes2)
            title(['Beads/cells detected. Processing time = ', num2str(ExecTime),' sec'])


            dist_threshold = 30;
            D=[];
            
            Bead_cnt = zeros(size(pos_cell,1),1);
            
            
            if k_cell>1
                if k_bead>1
            
                    for i=1:size(pos_cell,1)
            
            
                        D = pdist2(pos_cell(i,:),pos_bead,'euclidean');
            
            
                        if ~isempty(D)
                            [rows,cols] = find(D<dist_threshold & D>rad_cell(i));
            
                        else
                            rows=[];
                        end
            
            
                        if ~isempty(rows)
                            Bead_cnt(i) = size(rows,2);
            
            
                        end
                    end
                end
            end
            
            global pair
            
            pair = [pos_cell(:,1),pos_cell(:,2),Bead_cnt];


            c = clock;
            directory_name = app.text_dir,'String');
            name = app.name;
            nameindex = strfind(name,'.png');
            
            filename = [directory_name,'/',name(1:nameindex-1),'_',datestr(now,1),'_',num2str(c(4)),'-',num2str(c(5)),'.mat'];
            save(filename,'directory_name','name','IntCell')


            % app.Beadcnt = Bead_cnt;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: togglePhase
        function togglePhase_Callback(app, event)
          
            
            ReconImage = app.Recon;
            Phase = app.Phase;
            
            axes(app.axes2)
            xlimit = app.axes2,'Xlim');
            ylimit = app.axes2,'Ylim');
            
            minx = ceil(xlimit(1));
            maxx = floor(xlimit(2));
            miny = ceil(ylimit(1));
            maxy = floor(ylimit(2));
            
            % Adjust min and max boundaries if the xlim and ylim is out of hologram image
            if minx < 1
                minx = 1;
            end
            if maxx > size(ReconImage,2)
                maxx = size(ReconImage,2);
            end
            if miny < 1
                miny = 1;
            end
            if maxy > size(ReconImage,1)
                maxy = size(ReconImage,1);
            end
            
            Modulus = abs(ReconImage);
            
            % Show the phage information when it is selected. Otherwise, show the
            % amplitude (Modulus and togglephase)
            TogglePhase = hObject.Value;
            
            if TogglePhase
            
                Phasebackground = mean2(Phase)*3;
            %     Phasebackground = 0;
                if app.radiobuttonColor.Value
                [m,n,r] = size(Modulus);
                ReconColor = zeros(m,n,3);
                ReconColor(:,:,1) = Phase-Phasebackground;
                imagesc(ReconColor)
                end
            
            
                if app.radiobuttonGray.Value
                imagesc(Phase)
                colormap(hot)
                end
            
                axis equal
                title('Reconstructed phase')
                caxis([0 pi()])
            
            
            else
            
                if app.radiobuttonColor.Value
                    [m,n,r] = size(Modulus);
                    ReconColor = zeros(m,n,3);
                    ReconColor(:,:,2) = 1-abs(ReconImage);
                    imagesc(ReconColor)
                end
            
            
                if app.radiobuttonGray.Value
                    imagesc(Modulus)
                    colormap(gray)
                end
            
                axis equal
                caxis([0 1])
                title('Reconstructed amplitude')
            
            end
            
            xlim([minx maxx])
            ylim([miny maxy])
            
            guidata(hObject, handles);
        end

        % Value changed function: toggleSupport
        function toggleSupport_Callback(app, event)
            % hObject    handle to toggleSupport (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: hObject.Value returns toggle state of toggleSupport
            
            
            ReconImage = app.Recon;
            support = app.ObjSupp;
            axes(app.axes2)
            xlimit = app.axes2,'Xlim');
            ylimit = app.axes2,'Ylim');
            
            minx = ceil(xlimit(1));
            maxx = floor(xlimit(2));
            miny = ceil(ylimit(1));
            maxy = floor(ylimit(2));
            
            % Adjust min and max boundaries if the xlim and ylim is out of hologram image
            if minx < 1
                minx = 1;
            end
            if maxx > size(ReconImage,2)
                maxx = size(ReconImage,2);
            end
            if miny < 1
                miny = 1;
            end
            if maxy > size(ReconImage,1)
                maxy = size(ReconImage,1);
            end
            
            % ToggleSupport = app.Support.Value;
            
            if app.toggleSupport.Value
            
                imagesc(support)
                axis equal
                title('Object support')
            else
                imagesc(abs(ReconImage))
                axis equal
                title('Reconstructed amplitude')
                colormap('gray')
            end
            
            xlim([minx maxx])
            ylim([miny maxy])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'D3_Reconstruction_UI_Ver1c_Breast';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Reconstruction';
            app.axes1 = uiaxes(app.uipanel1);
            app.axes2 = uiaxes(app.uipanel1);
            app.uipanel3 = uipanel(app.uipanel1);
            app.uipanel3.Title = 'Load images';
            app.FileType = uieditfield(app.uipanel3, 'text');
            app.FileType.Value = 'png';
            app.LoadImages = uibutton(app.uipanel3, 'push');
            app.LoadImages.ButtonPushedFcn = createCallbackFcn(app, @LoadImages_Callback, true);
            app.LoadImages.Text = 'Load images';
            app.SetRef = uibutton(app.uipanel3, 'push');
            app.SetRef.ButtonPushedFcn = createCallbackFcn(app, @SetRef_Callback, true);
            app.SetRef.Text = 'Set references';
            app.listbox1 = uilistbox(app.uipanel3);
            app.listbox1.Items = {'Listbox', '1', '2'};
            app.listbox1.ValueChangedFcn = createCallbackFcn(app, @listbox1_Callback, true);
            app.listbox1.Value = 'Listbox';
            app.text11 = uilabel(app.uipanel3);
            app.text11.Text = 'Reference:';
            app.text12 = uilabel(app.uipanel3);
            app.text12.Text = 'Directory:';
            app.text_ref = uilabel(app.uipanel3);
            app.text_ref.Text = 'Not loaded';
            app.text_dir = uilabel(app.uipanel3);
            app.text_dir.Text = '';
            app.textWarning = uilabel(app.uipanel3);
            app.textWarning.Text = '';
            app.SaveAxis = uibutton(app.uipanel3, 'push');
            app.SaveAxis.ButtonPushedFcn = createCallbackFcn(app, @SaveAxis_Callback, true);
            app.SaveAxis.Text = 'Save axis';
            app.Auto = uibutton(app.uipanel3, 'push');
            app.Auto.ButtonPushedFcn = createCallbackFcn(app, @Auto_Callback, true);
            app.Auto.Text = 'Auto';
            app.Reconstruction = uibutton(app.uipanel1, 'push');
            app.Reconstruction.ButtonPushedFcn = createCallbackFcn(app, @Reconstruction_Callback, true);
            app.Reconstruction.Text = 'Reconstruction';
            app.uipanel4 = uipanel(app.uipanel1);
            app.uipanel4.Title = 'Parameters';
            app.text6 = uilabel(app.uipanel4);
            app.text6.Text = 'Wavelength (nm)';
            app.wavelength = uieditfield(app.uipanel4, 'text');
            app.wavelength.Value = '470';
            app.NumIter = uieditfield(app.uipanel4, 'text');
            app.NumIter.Value = '30';
            app.text7 = uilabel(app.uipanel4);
            app.text7.Text = '# of iteration';
            app.text8 = uilabel(app.uipanel4);
            app.text8.Text = 'Dz (x 100 um)';
            app.Dz = uieditfield(app.uipanel4, 'text');
            app.Dz.Value = '5';
            app.DzScan = uibutton(app.uipanel4, 'push');
            app.DzScan.ButtonPushedFcn = createCallbackFcn(app, @DzScan_Callback, true);
            app.DzScan.Text = 'z-scan';
            app.uipanel5 = uipanel(app.uipanel1);
            app.uipanel5.Title = 'Toggle menu';
            app.togglePhase = uibutton(app.uipanel5, 'state');
            app.togglePhase.ValueChangedFcn = createCallbackFcn(app, @togglePhase_Callback, true);
            app.togglePhase.Text = 'Phase';
            app.toggleSupport = uibutton(app.uipanel5, 'state');
            app.toggleSupport.ValueChangedFcn = createCallbackFcn(app, @toggleSupport_Callback, true);
            app.toggleSupport.Text = 'Obj support';
            app.toggleComposite = uibutton(app.uipanel5, 'state');
            app.toggleComposite.ValueChangedFcn = createCallbackFcn(app, @toggleComposite_Callback, true);
            app.toggleComposite.Text = 'Composite';
            app.toggleDetection = uibutton(app.uipanel5, 'push');
            app.toggleDetection.ButtonPushedFcn = createCallbackFcn(app, @toggleDetection_Callback, true);
            app.toggleDetection.Text = 'Detection';
            app.uibuttongroup2 = uibuttongroup(app.uipanel5);
            app.uibuttongroup2.Title = 'Color';
            app.radiobuttonGray = uiradiobutton(app.uibuttongroup2);
            app.radiobuttonGray.Text = 'Gray';
            app.radiobuttonColor = uiradiobutton(app.uibuttongroup2);
            app.radiobuttonColor.Text = 'RGB';
            app.radiobuttonColor.Value = true;
            app.uipanel7 = uipanel(app.uipanel1);
            app.uipanel7.Title = 'Export';
            app.DataExport = uibutton(app.uipanel7, 'push');
            app.DataExport.Text = 'Export';
            app.ExportFile = uieditfield(app.uipanel7, 'text');
            app.ExportFile.ValueChangedFcn = createCallbackFcn(app, @ExportFile_Callback, true);
            app.text9 = uilabel(app.uipanel7);
            app.text9.Text = 'Name';
            app.ExpDir = uicheckbox(app.uipanel7);
            app.ExpDir.Text = 'Dir name';
            app.ExpFile = uicheckbox(app.uipanel7);
            app.ExpFile.Text = 'File name';
            app.ExpHolo = uicheckbox(app.uipanel7);
            app.ExpHolo.Text = 'Hologram';
            app.ExpRecon = uicheckbox(app.uipanel7);
            app.ExpRecon.Text = 'ReconImage';
            app.ExpRange = uicheckbox(app.uipanel7);
            app.ExpRange.Text = 'Range';
            app.ExpObj = uicheckbox(app.uipanel7);
            app.ExpObj.Text = 'Obj Support';
            app.ExpComposite = uicheckbox(app.uipanel7);
            app.ExpComposite.Text = 'Composite';
            app.ExpAmp = uicheckbox(app.uipanel7);
            app.ExpAmp.Text = 'Amp & phase';
            app.uipanel8 = uipanel(app.uipanel1);
            app.uipanel8.Title = 'Thresholds';
            app.ThresholdObj = uieditfield(app.uipanel8, 'text');
            app.ThresholdObj.Value = '0.06';
            app.text10 = uilabel(app.uipanel8);
            app.text10.Text = 'Obj support';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = D3_Reconstruction_UI_Ver1c_Breast_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @D3_Reconstruction_UI_Ver1c_Breast_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end